classdef head_orientation_calibration_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        inputPanel                      matlab.ui.container.Panel
        plots_nameTextAreaLabel         matlab.ui.control.Label
        plots_nameTextArea              matlab.ui.control.TextArea
        startButton                     matlab.ui.control.Button
        scale_lengthmmSpinnerLabel      matlab.ui.control.Label
        scale_lengthmmSpinner           matlab.ui.control.Spinner
        skip_get_plotsSwitchLabel       matlab.ui.control.Label
        skip_get_plotsSwitch            matlab.ui.control.Switch
        reprojection_errorthresholdpixelSpinnerLabel  matlab.ui.control.Label
        reprojection_errorthresholdpixelSpinner  matlab.ui.control.Spinner
        outputPanel                     matlab.ui.container.Panel
        cameraCalibrator_doneLampLabel  matlab.ui.control.Label
        cameraCalibrator_doneLamp       matlab.ui.control.Lamp
        get_plots_doneLampLabel         matlab.ui.control.Label
        get_plots_doneLamp              matlab.ui.control.Lamp
        export_csv_doneLampLabel        matlab.ui.control.Label
        export_csv_doneLamp             matlab.ui.control.Lamp
        dateEditFieldLabel              matlab.ui.control.Label
        dateEditField                   matlab.ui.control.NumericEditField
        subject_nameEditFieldLabel      matlab.ui.control.Label
        subject_nameEditField           matlab.ui.control.EditField
        num_plotsEditFieldLabel         matlab.ui.control.Label
        num_plotsEditField              matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        mean_reprolectionerrorpixelEditFieldLabel  matlab.ui.control.Label
        mean_reprolectionerrorpixelEditField  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            %% set parameters. get basicinfo.   
            addpath(genpath('sfm_func'));
            plotname = app.plots_nameTextArea.Value{1};
            data.basicinfo.scaledistance = app.scale_lengthmmSpinner.Value; %mm      
            data.basicinfo.eachplotname = strsplit(plotname,',');
            app.num_plotsEditField.Value = length(data.basicinfo.eachplotname);
            data.basicinfo.numplots = app.num_plotsEditField.Value;
            path = uigetdir;
            sellen = pwd;
            data.basicinfo.selpath = strrep(path,strcat(sellen,'\'),'');
            filepath = strsplit(data.basicinfo.selpath,'\');
            data.basicinfo.date = str2double(filepath{2});
            data.basicinfo.crowname = filepath{3};
            app.dateEditField.Value = data.basicinfo.date;
            app.subject_nameEditField.Value = data.basicinfo.crowname;
            
            %% parameters
            data.cl_reconstruction.reprojectionerror_threshold = app.reprojection_errorthresholdpixelSpinner.Value; % pxl in 3840*2160.            
            data.cl_reconstruction.numbundle = 1000; % so far, this is enough for successful reconstruction.
            data.cl_reconstruction.maxnumtrials = 500; % default is 500. 2000  for estimateEssentialMatrix in cl_reconstruction.m.
            data.cl_reconstruction.maxdistance = 0.1; % default is 0.1.  0.01  for estimateEssentialMatrix in cl_reconstruction.m.
            data.cl_reconstruction.confidence = 99; % default is 99. for estimateEssentialMatrix in cl_reconstruction.m.
            data.cl_reconstruction.maxiterations = 50; % default is 50.  50 for bundleAdjustment in cl_reconstruction.m.
            data.cl_reconstruction.absolutetolerance = 1; % default is 1.  for bundleAdjustment in cl_reconstruction.m.
            data.cl_reconstruction.relativetolerance = 1e-5; % default is 1e-5.  for bundleAdjustment in cl_reconstruction.m.
            data.cl_reconstruction.fixedviewId = 1; % default is 1.  for bundleAdjustment in cl_reconstruction.m.            
            %%
            
            if ~isfile(strcat('calibration/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/calibrationSession.mat'))
                app.cameraCalibrator_doneLamp.Color = [1,0,0];
                app.get_plots_doneLamp.Color = [1,0,0];
                app.export_csv_doneLamp.Color = [1,0,0];
                errordlg(strcat('[calibration/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/calibrationSession.mat] is not found.  Use cameraCalibrator.'))
                error('Calibration file is not found.  Use cameraCalibrator.')
            else
                app.cameraCalibrator_doneLamp.Color = [0,1,0];
                app.get_plots_doneLamp.Color = [1,0,0];
                app.export_csv_doneLamp.Color = [1,0,0];
            end

            data.basicinfo.filetitle = 'calibration';
            calibration_temp = open(strcat('calibration/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/calibrationSession.mat'));
            data.basicinfo.intrinsics = calibration_temp.calibrationSession.CameraParameters;
            numimages = dir(strcat('photos/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/','*.png'));
            data.basicinfo.numimages = numel(numimages);
            
            %% load images & get raw coordinations            
            if isequal(app.skip_get_plotsSwitch.Value,'No')
                [img] = load_images(data);
                [data] = get_plot_coord(data,img);
                save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
                disp(strcat(num2str(data.basicinfo.date),'-',data.basicinfo.crowname,' point coordinates are saved. Processing the data now.'));
            else 
                disp(strcat('Loading ',num2str(data.basicinfo.date),'-',data.basicinfo.crowname,' point coordinates. Processing the data now.'));
            end
            
            app.cameraCalibrator_doneLamp.Color = [0,1,0];
            app.get_plots_doneLamp.Color = [0,1,0];
            app.export_csv_doneLamp.Color = [1,0,0];
            
           
            %% process raw data
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            [data] = process_raw_data(data);
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');    

            
            %% reconstruct calibration data
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');           
            [data] = cl_reconstruction(data);
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            
            
            %% convert axix unit to mm. reconstruct calibration data
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            [data] = post_cl_reconstruction(data);
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            
            
            %% estimate new plots coordinate
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            [data] = estimate_newplots(data);
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            
            
            %% convert axix unit to mm. reconstruct all data based on calibration data.
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            [data] = post_eye_reconstruction(data);
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            
            
            %% export data
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            [data] = export_data(data);
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            
            
            %% display scale_error(mm)
            load(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            [data] = scale_error(data);
            app.UITable.Data = data.table.scale;
            app.mean_reprolectionerrorpixelEditField.Value = data.cl_reconstruction.meanreprojectionError;
            save(strcat('matdata/',num2str(data.basicinfo.date),'/',data.basicinfo.crowname,'/data.mat'),'data');
            
            
            app.cameraCalibrator_doneLamp.Color = [0,1,0];
            app.get_plots_doneLamp.Color = [0,1,0];
            app.export_csv_doneLamp.Color = [0,1,0];
        end

        % Value changed function: dateEditField
        function dateEditFieldValueChanged(app, event)
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputPanel = uipanel(app.UIFigure);
            app.inputPanel.Title = 'input';
            app.plots_nameTextAreaLabel = uilabel(app.inputPanel);
            app.plots_nameTextAreaLabel.Text = 'plots_name';
            app.plots_nameTextArea = uitextarea(app.inputPanel);
            app.plots_nameTextArea.Value = {'Marker1,Marker2,Marker3,Marker4,Marker5,Scale1,Scale2,Scale3,nc-Eyeleft,nc-Eyeright,nc-Beak'};
            app.startButton = uibutton(app.inputPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.scale_lengthmmSpinnerLabel = uilabel(app.inputPanel);
            app.scale_lengthmmSpinnerLabel.Text = 'scale_length(mm)';
            app.scale_lengthmmSpinner = uispinner(app.inputPanel);
            app.scale_lengthmmSpinner.Value = 30;
            app.skip_get_plotsSwitchLabel = uilabel(app.inputPanel);
            app.skip_get_plotsSwitchLabel.Text = 'skip_get_plots';
            app.skip_get_plotsSwitch = uiswitch(app.inputPanel, 'slider');
            app.skip_get_plotsSwitch.Items = {'No', 'Yes'};
            app.skip_get_plotsSwitch.Value = 'No';
            app.reprojection_errorthresholdpixelSpinnerLabel = uilabel(app.inputPanel);
            app.reprojection_errorthresholdpixelSpinnerLabel.Text = {'reprojection_error'; 'threshold(pixel)'};
            app.reprojection_errorthresholdpixelSpinner = uispinner(app.inputPanel);
            app.reprojection_errorthresholdpixelSpinner.Value = 5;
            app.outputPanel = uipanel(app.UIFigure);
            app.outputPanel.Title = 'output';
            app.cameraCalibrator_doneLampLabel = uilabel(app.UIFigure);
            app.cameraCalibrator_doneLampLabel.Text = 'cameraCalibrator_done';
            app.cameraCalibrator_doneLamp = uilamp(app.UIFigure);
            app.get_plots_doneLampLabel = uilabel(app.UIFigure);
            app.get_plots_doneLampLabel.Text = 'get_plots_done';
            app.get_plots_doneLamp = uilamp(app.UIFigure);
            app.export_csv_doneLampLabel = uilabel(app.UIFigure);
            app.export_csv_doneLampLabel.Text = 'export_csv_done';
            app.export_csv_doneLamp = uilamp(app.UIFigure);
            app.dateEditFieldLabel = uilabel(app.UIFigure);
            app.dateEditFieldLabel.Text = 'date';
            app.dateEditField = uieditfield(app.UIFigure, 'numeric');
            app.dateEditField.ValueDisplayFormat = '%.0f';
            app.dateEditField.ValueChangedFcn = createCallbackFcn(app, @dateEditFieldValueChanged, true);
            app.dateEditField.Editable = 'off';
            app.subject_nameEditFieldLabel = uilabel(app.UIFigure);
            app.subject_nameEditFieldLabel.Text = 'subject_name';
            app.subject_nameEditField = uieditfield(app.UIFigure, 'text');
            app.subject_nameEditField.Editable = 'off';
            app.num_plotsEditFieldLabel = uilabel(app.UIFigure);
            app.num_plotsEditFieldLabel.Text = 'num_plots';
            app.num_plotsEditField = uieditfield(app.UIFigure, 'numeric');
            app.num_plotsEditField.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'scale_error(mm)'};
            app.UITable.RowName = {};
            app.mean_reprolectionerrorpixelEditFieldLabel = uilabel(app.UIFigure);
            app.mean_reprolectionerrorpixelEditFieldLabel.Text = 'mean_reprolectionerror(pixel)';
            app.mean_reprolectionerrorpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.mean_reprolectionerrorpixelEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = head_orientation_calibration_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end